classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        windSpeedLabel      matlab.ui.control.Label
        HumidityLabel       matlab.ui.control.Label
        TempLabel           matlab.ui.control.Label
        GetInfoButton       matlab.ui.control.Button
        AddCityButton       matlab.ui.control.Button
        CityEditField       matlab.ui.control.EditField
        CityEditFieldLabel  matlab.ui.control.Label
        CityDropDown        matlab.ui.control.DropDown
        CityDropDownLabel   matlab.ui.control.Label
        WindSpeedAxes       matlab.ui.control.UIAxes
        HumidityAxes        matlab.ui.control.UIAxes
        TempAxes            matlab.ui.control.UIAxes
    end

    % Özel erişimli özellikler tanımlanır
    properties (Access = private)
        apiKey = '8837203c2a21882aa541f6d408e564ab'; % API anahtarımız
        cities = ['Adana' , 'Ağrı', 'Adıyaman', 'Mersin', 'İstanbul', 'Ankara'];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddCityButton
        function AddCityButtonPushed(app, event)
            AddnewCity = app.CityEditField.Value; % Eklenen yeni şehir adı alınır

            if ~isempty(AddnewCity) % Şehir adı boş değilse, şehir listesine eklenir

            app.CityDropDown.Items{end+1} = AddnewCity;

            end
        end

        % Button pushed function: GetInfoButton
        function GetInfoButtonPushed(app, event)
                try
                    % Seçilen şehir adı alınır
                    selectedCity = app.CityDropDown.Value;
            
                    % Şehir adının bir dize olduğundan emin olunur
                    if ischar(selectedCity) || isstring(selectedCity)
                        % Beş günlük hava durumu tahminleri alınır
                        [temp, humidity, windSpeed, time] = fetchFiveDayForecast(selectedCity, app.apiKey);
            
                        % Sıcaklık grafiği çizilir ve eksen etiketleri eklenir
                        plot(app.TempAxes, time, temp, '-o');
                        title(app.TempAxes, sprintf('Sıcaklık (°C) Tahmini: %s', selectedCity));
                        xlabel(app.TempAxes, 'Tarih');
                        ylabel(app.TempAxes, 'Sıcaklık (°C)');
            
                        % Nem grafiği çizilir ve eksen etiketleri eklenir
                        plot(app.HumidityAxes, time, humidity, '-x');
                        title(app.HumidityAxes, sprintf('Nem Tahmini: %s', selectedCity));
                        xlabel(app.HumidityAxes, 'Tarih');
                        ylabel(app.HumidityAxes, 'Nem');
            
                        % Rüzgar hızı grafiği çizilir ve eksen etiketleri eklenir
                        plot(app.WindSpeedAxes, time, windSpeed, '-o');
                        title(app.WindSpeedAxes, sprintf('Rüzgar Hızı Tahmini: %s', selectedCity));
                        xlabel(app.WindSpeedAxes, 'Tarih');
                        ylabel(app.WindSpeedAxes, 'Rüzgar Hızı (m/s)');
            
                        % İlk tahmin değerleri etiketlenir ve gösterilir
                        app.TempLabel.Text = sprintf('Sıcaklık: %1.f °C', temp(1));
                        app.HumidityLabel.Text = sprintf('Nem: %1.f%%', humidity(1));
                        app.windSpeedLabel.Text = sprintf('Rüzgar Hızı: %2.f m/s', windSpeed(1));
                    else
                        % Hata durumunda kullanıcıya bilgi verilir
                        msgbox('Lütfen bir şehir seçin.', 'Hata', 'error');
                    end
                catch exception
                    % Hata mesajını görüntüler
                    disp(exception.message);
                end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TempAxes = uiaxes(app.UIFigure);
            title(app.TempAxes, 'Temperature Forecast For')
            xlabel(app.TempAxes, 'Date')
            ylabel(app.TempAxes, 'Temperature')
            zlabel(app.TempAxes, 'Z')
            app.HumidityAxes = uiaxes(app.UIFigure);
            title(app.HumidityAxes, {'Humidity Forecast For'; ''})
            xlabel(app.HumidityAxes, 'Date')
            ylabel(app.HumidityAxes, 'Humidity')
            zlabel(app.HumidityAxes, 'Z')
            app.WindSpeedAxes = uiaxes(app.UIFigure);
            title(app.WindSpeedAxes, {'Wind Speed Forecast For'; ''})
            xlabel(app.WindSpeedAxes, 'Date')
            ylabel(app.WindSpeedAxes, 'Wind Speed')
            zlabel(app.WindSpeedAxes, 'Z')
            app.CityDropDownLabel = uilabel(app.UIFigure);
            app.CityDropDownLabel.Text = 'City Drop Down';
            app.CityDropDown = uidropdown(app.UIFigure);
            app.CityDropDown.Items = {};
            app.CityDropDown.Value = {};
            app.CityEditFieldLabel = uilabel(app.UIFigure);
            app.CityEditFieldLabel.Text = 'City Edit Field';
            app.CityEditField = uieditfield(app.UIFigure, 'text');
            app.AddCityButton = uibutton(app.UIFigure, 'push');
            app.AddCityButton.ButtonPushedFcn = createCallbackFcn(app, @AddCityButtonPushed, true);
            app.AddCityButton.Text = 'Add City';
            app.GetInfoButton = uibutton(app.UIFigure, 'push');
            app.GetInfoButton.ButtonPushedFcn = createCallbackFcn(app, @GetInfoButtonPushed, true);
            app.GetInfoButton.Text = 'Get Info';
            app.TempLabel = uilabel(app.UIFigure);
            app.TempLabel.Text = 'TempLabel';
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'HumidityLabel';
            app.windSpeedLabel = uilabel(app.UIFigure);
            app.windSpeedLabel.Text = 'windSpeedLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end